classdef CruiseControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculateButton               matlab.ui.control.Button
        VehiclemassEditFieldLabel     matlab.ui.control.Label
        VehiclemassEditField          matlab.ui.control.EditField
        EngineTorqueEditFieldLabel    matlab.ui.control.Label
        EngineTorqueEditField         matlab.ui.control.EditField
        GearRatioEditFieldLabel       matlab.ui.control.Label
        GearRatioEditField            matlab.ui.control.EditField
        EnginetimeEditFieldLabel      matlab.ui.control.Label
        EnginetimeEditField           matlab.ui.control.EditField
        GravityEditFieldLabel         matlab.ui.control.Label
        GravityEditField              matlab.ui.control.EditField
        AirDensityEditFieldLabel      matlab.ui.control.Label
        AirDensityEditField           matlab.ui.control.EditField
        DragcoefficentEditFieldLabel  matlab.ui.control.Label
        DragcoefficentEditField       matlab.ui.control.EditField
        FrontAeraEditFieldLabel       matlab.ui.control.Label
        FrontAeraEditField            matlab.ui.control.EditField
        WheelRadiusEditFieldLabel     matlab.ui.control.Label
        WheelRadiusEditField          matlab.ui.control.EditField
        RollingResEditFieldLabel      matlab.ui.control.Label
        RollingResEditField           matlab.ui.control.EditField
        SwitchLabel                   matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            % Part 1 - Simulation of automobile cruise control

            %simulate the speed of an automobile based on a basic traction model as it 
            %travels a road of varying grade. Simulate PID control of the vehicle s speed
            %and study how it responds to a sudden change in grade as a function of control parameters.
            
            
            % SLOPE TEST FILE
            
             % Input Constants
             
             m = app.VehiclemassEditField.Value; % mass in kg
             f = app.RollingResEditField.Value; % rolling resistance coefficient
             p = app.AirDensityEditField.Value; % density of air in kg/m^3
             Cd = app.DragcoefficentEditField.Value; % drag coefficient
             A = app.FrontAeraEditField.Value; % area of front cross section in m^2
             g = app.GravityEditField.Value; % gravity
             tau = app.EnginetimeEditField.Value; % engine time constant in s
             k = app.EngineTorqueEditField.Value; % engine torque gain factor Nm/rad
             i = app.GearRatioEditField.Value; % gear ration
             Rw = app.WheelRadiusEditField.Value; % wheel radius in m
            
             % Variable Initilizations
             
            v = 40; % initialized velocity at t = 0
            x = 0; % initialized position at t = 0
            a = 0; % initialized acceleration at t = 0
            Fw = 0; % initialized force application to wheels at t = 0
            theta = 0; % road inclinitation at t = 0
            u = 0; % accelerator position in radians
            
            % time interval
            dt = .01; % time increment !! Not Seconds !!
            td = 16; % time stop !! Not Seconds !!
            t = (0:dt:td);
            
            % PID Values
            V_set = 60;
            P = -0.0006;
            I = -0.00001;
            D = -0.45;
            N = 3;
            
            % Step Change Simulation Loop
            for int = (1:length(t))
                % sudden uphill grade
                %Slope change
                if t(int) >= td/2 && app.Switch(1)
                    theta = 2;
                end
                
                %Step change
                if t(int) >= td/2 && app.Switch(2)
                    V_set = 65;
                end
                Fw(end + 1) = ((-Fw(end)/tau) + (k*i/tau*Rw)* u(end)) + Fw(end);
                v(end + 1) = (v(end) + a(end) * t(int));
                a(end + 1) = ((Fw(end - 1) - m*g*f*cosd(theta) - .5*p*Cd*A*(v(end - 1)^2) - m*g*sind(theta))/m);
                x(end + 1) = x(end) + v(end - 1);
                u(end + 1) = u(end) + controlalg(v(end - 1),V_set,P,I,D,N); % using control algorithm function
            end
            
            
            % Plot
            time = (0:length(x)-1);
            
            yyaxis left
            plot(app.UIAxes,time,x,'b')
            hold on
            yyaxis right
            plot(app.UIAxes,time,v,'r')
            hold on
            plot(app.UIAxes,time,a,'k')
            legend('pos','velocity','acceleration','Location','best')
            title('Velocity, Position, Acceleration vs Time')
            xlabel('Time (seconds)')
            ylabel('Meters')
            hold off

            
            function[y] = controlalg(T, T_set, P,I,D,N)
% P = proportional to current error
% I = proportional to integral of last several errors
% D = proportional to difference between last two error values
x = T-T_set;
if length(x) == 1
    y = P*x(end);
    
elseif length(x)>1&length(x)<N
        y = P*x(end)+ D*(x(end)-x(end-1))+I*sum(x);
        
elseif length(x)>=N
            y = P*x(end)+ D*(x(end)-x(end-1))+I*sum(x(end-N+1:end));
%else y = P*x(end)+I*sum(x)
end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.VehiclemassEditFieldLabel = uilabel(app.UIFigure);
            app.VehiclemassEditFieldLabel.Text = {'Vehicle mass'; ''};
            app.VehiclemassEditField = uieditfield(app.UIFigure, 'text');
            app.EngineTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.EngineTorqueEditFieldLabel.Text = 'Engine Torque';
            app.EngineTorqueEditField = uieditfield(app.UIFigure, 'text');
            app.GearRatioEditFieldLabel = uilabel(app.UIFigure);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.UIFigure, 'text');
            app.EnginetimeEditFieldLabel = uilabel(app.UIFigure);
            app.EnginetimeEditFieldLabel.Text = {'Engine time '; ''};
            app.EnginetimeEditField = uieditfield(app.UIFigure, 'text');
            app.GravityEditFieldLabel = uilabel(app.UIFigure);
            app.GravityEditFieldLabel.Text = 'Gravity';
            app.GravityEditField = uieditfield(app.UIFigure, 'text');
            app.AirDensityEditFieldLabel = uilabel(app.UIFigure);
            app.AirDensityEditFieldLabel.Text = 'Air Density';
            app.AirDensityEditField = uieditfield(app.UIFigure, 'text');
            app.DragcoefficentEditFieldLabel = uilabel(app.UIFigure);
            app.DragcoefficentEditFieldLabel.Text = 'Drag coefficent';
            app.DragcoefficentEditField = uieditfield(app.UIFigure, 'text');
            app.FrontAeraEditFieldLabel = uilabel(app.UIFigure);
            app.FrontAeraEditFieldLabel.Text = 'Front Aera';
            app.FrontAeraEditField = uieditfield(app.UIFigure, 'text');
            app.WheelRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.WheelRadiusEditFieldLabel.Text = 'Wheel Radius';
            app.WheelRadiusEditField = uieditfield(app.UIFigure, 'text');
            app.RollingResEditFieldLabel = uilabel(app.UIFigure);
            app.RollingResEditFieldLabel.Text = 'Rolling Res.';
            app.RollingResEditField = uieditfield(app.UIFigure, 'text');
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Slope Test', 'Step Test'};
            app.Switch.Value = 'Slope Test';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CruiseControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end