classdef TicTacToe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TicTacToeLabel           matlab.ui.control.Label
        ChooseFateDropDownLabel  matlab.ui.control.Label
        ChooseFateDropDown       matlab.ui.control.DropDown
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              imshow('tictactoe.jpg', 'Parent', app.UIAxes)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

                         click_position = app.UIAxes.CurrentPoint
                        x_position = click_position(1,1)
                        y_position = click_position(2,2)
                        
                        %UIAxes1,1
                        if x_position < 200 && y_position<150
                            
                            px=[0,130,130,0]
                            py=[0,0, 130, 130]
                            if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                            else 
                                patch(app.UIAxes,px,py,'k')
                            end
                            
                            %UIAxes1,2
                        elseif x_position < 300 && y_position<150
                            
                            px=[150,260,260,150]
                            py=[0, 0, 130, 130]
                            
                            if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                            end
                            
                            %UIAxes1,3
                            elseif x_position > 300 && x_position < 400 && y_position<150
                          
                            px=[280,390,390,280]
                            py=[0, 0, 130, 130]
                            if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                            end
                            
                            %UIAxes2,1
                            elseif x_position < 200 && y_position < 200
                            
                            px=[150,260,260,150]
                            py=[150, 150, 0, 0]
                            if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                            end
                            
                            %UIAxes2,2
                        elseif x_position < 300 && x_position < 250 && y_position > 150 && y_position < 250
                            
                            px = [150,260,260,150]
                            py = [250,250,150,150]
                             if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                             end
                             
                             %UIAxes2,3
                          elseif x_position < 300 && x_position > 250 && y_position > 150 && y_position < 250
                            
                            px = [150,260,260,150]
                            py = [250,250,150,150]
                             if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                             end
                             
                             
                             %UIAxes3,1
                           elseif x_position < 150 && y_position > 300
                            
                            px = [0,130,130,0]
                            py = [500,500,170,170]
                             if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                             end


                             %UIAxes3,2
                          elseif x_position < 300 && y_position < 150 
                            
                            px = [150,260,260,150]
                            py = [250,250,150,150]
                             if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                             end           
                             
                             %UIAxes3,3
                          elseif x_position > 300 && x_position < 400 && y_position < 100
                            
                            px = [0,0,100,100]
                            py = [0,250,0,150]
                             if app.ChooseFateDropDown.Value == "Player 1"
                            patch(app.UIAxes,px,py,'r')
                             else 
                                patch(app.UIAxes,px,py,'k')
                             end
                            
                        end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.TicTacToeLabel = uilabel(app.UIFigure);
            app.TicTacToeLabel.Text = 'TicTacToe!!!';
            app.ChooseFateDropDownLabel = uilabel(app.UIFigure);
            app.ChooseFateDropDownLabel.Text = 'Choose Fate';
            app.ChooseFateDropDown = uidropdown(app.UIFigure);
            app.ChooseFateDropDown.Items = {'Player 1', 'Player 2', ''};
            app.ChooseFateDropDown.Value = 'Player 1';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'To play, try to either get three red or black boxes in a row'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end