classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        HeightEditFieldLabel    matlab.ui.control.Label
        HeightEditField         matlab.ui.control.NumericEditField
        launcherEditFieldLabel  matlab.ui.control.Label
        launcherEditField       matlab.ui.control.NumericEditField
        initialVEditFieldLabel  matlab.ui.control.Label
        initialVEditField       matlab.ui.control.NumericEditField
        distanceEditFieldLabel  matlab.ui.control.Label
        distanceEditField       matlab.ui.control.NumericEditField
        heightEditFieldLabel    matlab.ui.control.Label
        heightEditField         matlab.ui.control.NumericEditField
        widthEditFieldLabel     matlab.ui.control.Label
        widthEditField          matlab.ui.control.NumericEditField
        ThetaEditFieldLabel     matlab.ui.control.Label
        ThetaEditField          matlab.ui.control.NumericEditField
        CalculateButton         matlab.ui.control.Button
        OutcomeLabel            matlab.ui.control.Label
        ClearButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           theta =  app.ThetaEditField.Value;
           l = app.launcherEditField.Value;
           w = app.widthEditField.Value;
           h = app.heightEditField.Value;
           d = app.distanceEditField.Value;
           i_V = app.initialVEditField.Value;
           H = app.HeightEditField.Value;
           g = 9.81;
           
           x = [(l / 2) * cosd(theta):0.01:d + w];
           y = H + (l/2) * sind(theta) + tand(theta) * (x - (l/2) * cosd(theta)) - (1/2) * g * ((x-(l/2) * cosd(theta)) / (i_V * cosd(theta))).^2;
           
           plot(app.UIAxes,x(y>=0),y(y>=0), 'b')
          
           b = find(y<=h)
      
           if x(b(1)) >= d && x(b(1)) <= d + w   
           patch(app.UIAxes,[d,d+w,d+w,d],[0,0,h,h],'g')
           app.OutcomeLabel.Text = ['You hit!']
          
           else
               patch(app.UIAxes,[d,d+w,d+w,d],[0,0,h,h],'r') 
               app.OutcomeLabel.Text = ['You miss:(']
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.HeightEditFieldLabel = uilabel(app.Panel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.Panel, 'numeric');
            app.HeightEditField.Value = 2;
            app.launcherEditFieldLabel = uilabel(app.Panel);
            app.launcherEditFieldLabel.Text = 'launcher';
            app.launcherEditField = uieditfield(app.Panel, 'numeric');
            app.launcherEditField.Value = 3;
            app.initialVEditFieldLabel = uilabel(app.Panel);
            app.initialVEditFieldLabel.Text = 'initial V';
            app.initialVEditField = uieditfield(app.Panel, 'numeric');
            app.initialVEditField.Value = 5;
            app.distanceEditFieldLabel = uilabel(app.Panel);
            app.distanceEditFieldLabel.Text = 'distance';
            app.distanceEditField = uieditfield(app.Panel, 'numeric');
            app.distanceEditField.Value = 7;
            app.heightEditFieldLabel = uilabel(app.Panel);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.Panel, 'numeric');
            app.heightEditField.Value = 2;
            app.widthEditFieldLabel = uilabel(app.Panel);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.Panel, 'numeric');
            app.widthEditField.Value = 1;
            app.ThetaEditFieldLabel = uilabel(app.Panel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.Panel, 'numeric');
            app.ThetaEditField.Value = 15;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.OutcomeLabel = uilabel(app.UIFigure);
            app.OutcomeLabel.Text = 'Outcome';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Clear';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Launch')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Height')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end